classdef NatickWeather < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NatickWeatherStationUIFigure  matlab.ui.Figure
        AppGrid                       matlab.ui.container.GridLayout
        StatusPanel                   matlab.ui.container.Panel
        StatusGrid                    matlab.ui.container.GridLayout
        ShowRawDataCheckBox           matlab.ui.control.CheckBox
        GridLayout                    matlab.ui.container.GridLayout
        StatusImage                   matlab.ui.control.Image
        TimeLabel                     matlab.ui.control.Label
        RefreshDataLabel              matlab.ui.control.Label
        ContentGrid                   matlab.ui.container.GridLayout
        WeatherChartGrid              matlab.ui.container.GridLayout
        DaySwitch                     matlab.ui.control.Switch
        WeatherChartPanel             matlab.ui.container.Panel
        YesterPanel                   matlab.ui.container.Panel
        TodayPanel                    matlab.ui.container.Panel
        LocationPanel                 matlab.ui.container.Panel
        PressurePanel                 matlab.ui.container.Panel
        PressureGrid                  matlab.ui.container.GridLayout
        WindTitle                     matlab.ui.control.Label
        PressureReadout               matlab.ui.control.NumericEditField
        RainReadout                   matlab.ui.control.NumericEditField
        RainTitle                     matlab.ui.control.Label
        PressureTitle                 matlab.ui.control.Label
        TempPanel                     matlab.ui.container.Panel
        TempGrid                      matlab.ui.container.GridLayout
        CFSwitch                      matlab.ui.control.Switch
        HumidLabel                    matlab.ui.control.Label
        HumidGauge                    matlab.ui.control.Gauge
        HumidityLabel                 matlab.ui.control.Label
        FTempGauge                    matlab.ui.control.LinearGauge
        TempLabel                     matlab.ui.control.Label
        CTempGauge                    matlab.ui.control.LinearGauge
        CurrentConditionsLabel        matlab.ui.control.Label
        HeaderPanel                   matlab.ui.container.Panel
        HeaderGrid                    matlab.ui.container.GridLayout
        HelpIcon                      matlab.ui.control.Image
        AppIcon                       matlab.ui.control.Image
        DownloadIcon                  matlab.ui.control.Image
        NatickWeatherDashboardLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data
        
        TodaysData
        
        YesterData
        
        RefreshTimeLeft = 60;
        AnimateBkColorTimer;
        ComponentsAreCreated logical = false;
        CurrentNatickTime datetime;
        
        CmapRange = 100;
        Styles;
    end
    
    properties (Access = public)
        WindReadout UIWind;
        TodayWeatherChartInstance WeatherChart;
        YesterWeatherChartInstance WeatherChart;
        DataTable matlab.ui.control.Table;
    end
    
    properties (Constant, Access = public)
        AppLocation = fileparts(mfilename("fullpath")); % Description
    end
    
    methods (Access = private)
        
        function results = getImagePath(app,filename)
            results = fullfile(app.AppLocation,'images',filename);
        end

        function sizeAppToWindow(app)
            wm = connector.internal.webwindowmanager.instance();
            pos = wm.defaultPosition;
            app.NatickWeatherStationUIFigure.Position = [3 3 pos(3)-6 pos(4)-25];
            app.NatickWeatherStationUIFigureSizeChanged([]);
        end
        
        function loadData(app, forceLoad)
            if (nargin < 2)
                forceLoad = false;
            end
            app.CurrentNatickTime = datetime('now','TimeZone','America/New_York','Format','HH:mm:ss');
            
            if(app.RefreshTimeLeft <= 0 || forceLoad)
                app.Data = thingSpeakRead(12397,'OutputFormat','table');
                app.TodaysData = thingSpeakRead(12397,'DateRange',[(datetime("today",'TimeZone','America/New_York')) (datetime("now",'TimeZone','America/New_York'))],'OutputFormat','table');
                
                app.updateWidgets()
                app.RefreshTimeLeft = 60;
            end
            app.RefreshDataLabel.Text = sprintf('Data refreshes in %ds',app.RefreshTimeLeft);
            app.TimeLabel.Text = ['🕰 ', char(app.CurrentNatickTime)];
            app.RefreshTimeLeft = app.RefreshTimeLeft - 1;
            app.sizeAppToWindow();
        end
        
        function updateWidgets(app)
            app.HumidGauge.Value = app.Data.Humidity;
            app.PressureReadout.Value = app.Data.PressureHg;
            app.setTempLabel()
            app.RainReadout.Value = app.Data.RainInchesminute;
            app.WindReadout.Speed = app.Data.WindSpeedmph;
            app.WindReadout.Direction = app.Data.WindDirectionNorth0Degrees;
            app.HumidLabel.Text = [newline newline num2str(app.HumidGauge.Value) ' %'];
            app.TodayWeatherChartInstance.Data = app.TodaysData;
        end
        
        function setTempLabel(app)
            tempF= app.Data.TemperatureF;
            tempC = 5*(tempF-32)/9;
            tempNum = tempF;
            if strcmp(app.CFSwitch.Value,'C')
                tempNum = tempC;
            end
            app.TempLabel.Text = ['🌡 ' num2str(round(tempNum)) '°' app.CFSwitch.Value ' 🌡'];
            app.CTempGauge.Value = tempC;
            app.FTempGauge.Value = tempF;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Opening the App. Please Wait ...');

            %app.NatickWeatherStationUIFigure.Visible = 'off';
            app.WindReadout = UIWind(app.PressureGrid);
            app.WindReadout.Layout.Row = 4;
            app.WindReadout.Layout.Column = [1 2];
            
            app.DaySwitch.ItemsData = [true false];
            
            app.AppIcon.ImageSource = app.getImagePath('mw_rainbow.png');
            app.HelpIcon.ImageSource = fullfile(toolboxdir('matlab'),'uitools','private','icon_quest_32.png');
            app.DownloadIcon.ImageSource = app.getImagePath('download.png');
            app.StatusImage.ImageSource = app.getImagePath('loading.gif');
            
            app.TodayWeatherChartInstance = WeatherChart(app.TodayPanel);
            app.YesterWeatherChartInstance = WeatherChart(app.YesterPanel,'Data',app.YesterData);
            app.TodayPanel.Visible = false;
            app.YesterPanel.Visible = false;
            
            ga = geoaxes(app.LocationPanel,'Position',[0 0 1 1]);
            geoplot(ga,42.299676,-71.350525,'p','MarkerFaceColor','b','MarkerSize',20);
            % geobasemap(ga,'topographic');
            geolimits(ga,[41.6832 42.9102],[-72.1471 -70.5539]);
            ga.LatitudeAxis.Visible = 'off';
            ga.LongitudeAxis.Visible = 'off';
            ga.InnerPosition = [0 0 1 1];
            app.LocationPanel.AutoResizeChildren = 'off';
            app.LocationPanel.SizeChangedFcn = @(o,e) set(ga,'Position',[0 0 1 1]);
            ga.Interactions = [panInteraction dataTipInteraction];
            
            app.DataTable = uitable(app.ContentGrid, ...
                'Visible','off','ColumnSortable',true, ...
                'ColumnName', {'🕰 Time','🌡 Temp','💧 Humidity','💨 Wind'});
            app.DataTable.Layout.Row = [1 4];
            app.DataTable.Layout.Column = [1 3];
            cmap = jet(app.CmapRange);
            app.Styles = cell(app.CmapRange,1);
            for k = 1:app.CmapRange
                app.Styles{k}= uistyle('BackgroundColor',cmap(k,:));
            end
            
            % Fist Data Load
            app.loadData(true);
            app.YesterData = thingSpeakRead(12397,'DateRange',[(datetime("yesterday",'TimeZone','America/New_York')) (datetime("today",'TimeZone','America/New_York'))],'OutputFormat','table');
            app.YesterWeatherChartInstance.Data = app.YesterData;
            
            % Now make the figure visible. 
            %movegui(app.NatickWeatherStationUIFigure,'center');
            app.NatickWeatherStationUIFigure.Visible = 'on';
            app.CFSwitchValueChanged([]);            
            app.ComponentsAreCreated = true;
            app.sizeAppToWindow();
            
            refreshDataTimer = timer("ExecutionMode",'fixedRate',...
                "Period",1,"TimerFcn",@(o,e) app.loadData,...
                "StartDelay",5, ...
                "StopFcn",@(o,e) delete(o),...
                "Name","WeatherApp-RefreshData");
            start(refreshDataTimer);
            
            app.NatickWeatherStationUIFigure.DeleteFcn = @(o,e) stop(refreshDataTimer);            
        end

        % Close request function: NatickWeatherStationUIFigure
        function NatickWeatherStationUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Image clicked function: HelpIcon
        function HelpIconImageClicked(app, event)
            bullet = char(9670);
            m = sprintf('%s This is a demo MATLAB App.\n',bullet);
            m = sprintf('%s\n%s The App uses components such as UIImage, UIHTML, UIGridLayout etc.\n',m,bullet);
            m = sprintf('%s\n%s The App uses ThingSpeak to read weather data from the <a href="https://thingspeak.com/channels/12397">MathWorks Weather Station</a>.',m,bullet);
            
            uialert(app.NatickWeatherStationUIFigure,m,'About this app',...
                'Icon',app.getImagePath('mw_rainbow.png'), ...
                'Interpreter','html');
        end

        % Image clicked function: AppIcon
        function AppIconImageClicked(app, event)
            web('https://thingspeak.com/channels/12397','-browser');
        end

        % Value changed function: CFSwitch
        function CFSwitchValueChanged(app, event)
            value = app.CFSwitch.Value;
            if strcmp(value,'F')
                app.CTempGauge.Visible = 'off';
                app.FTempGauge.Visible = 'on';
            else
                app.CTempGauge.Visible = 'on';
                app.FTempGauge.Visible = 'off';
            end
            app.setTempLabel();
        end

        % Size changed function: NatickWeatherStationUIFigure
        function NatickWeatherStationUIFigureSizeChanged(app, event)
            position = app.NatickWeatherStationUIFigure.Position;
            currentFigureWidth = position(3);
            currentFigureHeight = position(4);
            if ~app.ComponentsAreCreated
                return;
            end
            
            if (currentFigureWidth < 440)
                
                app.ContentGrid.RowHeight = {240, 240, 240, '1x'};
                app.ContentGrid.ColumnWidth = {'1x',0,0};
                if (currentFigureHeight <= 1000)
                    app.ContentGrid.RowHeight{4} = 240;
                end
                
                app.TempPanel.Layout.Row = 1;
                app.TempPanel.Layout.Column = [1 3];
                
                app.PressurePanel.Layout.Row = 2;
                app.PressurePanel.Layout.Column = [1 3];
                
                app.LocationPanel.Layout.Row = 3;
                app.LocationPanel.Layout.Column = [1 3];
                
                app.NatickWeatherDashboardLabel.FontSize = 12;
                
            elseif (currentFigureWidth >= 440) && (currentFigureWidth <= 640)
                
                app.ContentGrid.RowHeight = {240, 0, 240, '1x'};
                app.ContentGrid.ColumnWidth = {'1x','1x',0};
                
                if (currentFigureHeight <= 840)
                    app.ContentGrid.RowHeight{4} = 240;
                end
                
                app.TempPanel.Layout.Row = 1;
                app.TempPanel.Layout.Column = 1;
                
                app.PressurePanel.Layout.Row = 1;
                app.PressurePanel.Layout.Column = [2 3];
                
                app.LocationPanel.Layout.Row = 3;
                app.LocationPanel.Layout.Column = [1 3];
                
                app.NatickWeatherDashboardLabel.FontSize = 14;
                
            else % > 640
                
                app.ContentGrid.RowHeight = {240, 0, 0, '1x'};
                app.ContentGrid.ColumnWidth = {240,240,'1x'};
                if (currentFigureHeight <= 560)
                    app.ContentGrid.RowHeight{4} = 240;
                end
                
                app.TempPanel.Layout.Row = 1;
                app.TempPanel.Layout.Column = 1;
                
                app.PressurePanel.Layout.Row = 1;
                app.PressurePanel.Layout.Column = 2;
                
                app.LocationPanel.Layout.Row = 1;
                app.LocationPanel.Layout.Column = 3;
                
                app.NatickWeatherDashboardLabel.FontSize = 18;
                
            end
            
        end

        % Image clicked function: DownloadIcon
        function DownloadIconImageClicked(app, event)
            web('https://github.com/varunrgandhi/MATLAB-Natick-Weather-App', ...
                '-browser');
        end

        % Value changed function: DaySwitch
        function DaySwitchValueChanged(app, event)
            value = app.DaySwitch.Value;
            pos = app.WeatherChartPanel.Position;
            
            controlPoints = 10;
            if (value) % today view
                app.TodayPanel.Visible = value;
                todayControlPoints = linspace(-pos(3),1,controlPoints);
                yesterControlPoints = linspace(1,pos(3),controlPoints);
            else
                app.YesterPanel.Visible = ~value;
                todayControlPoints = linspace(1,-pos(3),controlPoints);
                yesterControlPoints = linspace(pos(3),1,controlPoints);
            end
            
            % animate
            for k = 1:controlPoints
                app.TodayPanel.Position(1) = todayControlPoints(k);
                app.YesterPanel.Position(1) = yesterControlPoints(k);
                %drawnow update;
                pause(.025);
            end
            
            app.TodayPanel.Visible = value;
            app.YesterPanel.Visible = ~value;
        end

        % Size changed function: WeatherChartPanel
        function WeatherChartPanelSizeChanged(app, event)
            value = app.DaySwitch.Value;
            pos = app.WeatherChartPanel.Position;
            if ~app.ComponentsAreCreated
                return;
            end
            
            if (value) % today view
                app.TodayPanel.Position = [1 1 pos(3:4)];
                app.YesterPanel.Position = [pos(3) 1 pos(3:4)];
            else
                app.TodayPanel.Position = [-pos(3) 1 pos(3:4)];
                app.YesterPanel.Position = [1 1 pos(3:4)];
            end
            
            app.TodayPanel.Visible = value;
            app.YesterPanel.Visible = ~value;
        end

        % Value changed function: ShowRawDataCheckBox
        function ShowRawDataCheckBoxValueChanged(app, event)
            value = app.ShowRawDataCheckBox.Value;
            app.DataTable.Visible = value;
            
            if ~value
                return;
            end
            d = [app.YesterData; app.TodaysData];
            thinnedData = d(round(linspace(1,height(d),app.CmapRange)),1:5);
            thinnedData = movevars(thinnedData,5,"After",1);
            thinnedData = movevars(thinnedData,5,"After",2);
            
            function out = getWindText(dir,speed)
                arrow = "⬆️";
                if (dir > 22.5 && dir <= 67.5)
                    arrow = "↗️";
                elseif (dir > 67.5 && dir <= 112.5)
                    arrow = "➡️";
                elseif (dir > 112.5 && dir <= 157.5)
                    arrow = "↘️";
                elseif (dir > 157.5 && dir <= 202.5)
                    arrow = "⬇️";
                elseif (dir > 202.5 && dir <= 247.5)
                    arrow = "↙️";
                elseif (dir > 247.5 && dir <= 292.5)
                    arrow = "⬅️";
                elseif (dir > 292.5 && dir <= 337.5)
                    arrow = "↖️";
                end
                
                out = sprintf("%s %3d° - %3.1fmph",arrow,dir,speed);
                
            end
            windCol = rowfun(@getWindText,thinnedData,'InputVariables',["WindDirectionNorth0Degrees" "WindSpeedmph"]);
            thinnedData(:,4:5) = [];
            thinnedData = [thinnedData windCol];
            
            thinnedData = sortrows(thinnedData,'Timestamps','descend');
            
            temp = [thinnedData.TemperatureF];
            normTemp = round(normalize(temp,'range',[1 app.CmapRange]));
            app.DataTable.Data = thinnedData;
            
            removeStyle(app.DataTable);
            for k = 1:length(normTemp)
                addStyle(app.DataTable,app.Styles{normTemp(k)},'cell',[k,2])
            end
            % Bold TimeStamps
            addStyle(app.DataTable,uistyle('FontWeight','bold'),'Column',1);
            % Humidity is blue and centered
            addStyle(app.DataTable,uistyle('FontColor','b','HorizontalAlignment','center'),'Column',3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NatickWeatherStationUIFigure = uifigure('Visible', 'off');
            app.NatickWeatherStationUIFigure.Name = 'Natick Weather Station';
            app.NatickWeatherStationUIFigure.CloseRequestFcn = createCallbackFcn(app, @NatickWeatherStationUIFigureCloseRequest, true);
            app.NatickWeatherStationUIFigure.SizeChangedFcn = createCallbackFcn(app, @NatickWeatherStationUIFigureSizeChanged, true);
            app.AppGrid = uigridlayout(app.NatickWeatherStationUIFigure);
            app.HeaderPanel = uipanel(app.AppGrid);
            app.HeaderGrid = uigridlayout(app.HeaderPanel);
            app.NatickWeatherDashboardLabel = uilabel(app.HeaderGrid);
            app.NatickWeatherDashboardLabel.Text = 'Natick Weather Dashboard';
            app.DownloadIcon = uiimage(app.HeaderGrid);
            app.DownloadIcon.ImageClickedFcn = createCallbackFcn(app, @DownloadIconImageClicked, true);
            app.DownloadIcon.Tooltip = {'Download the Source Code'};
            app.AppIcon = uiimage(app.HeaderGrid);
            app.AppIcon.ImageClickedFcn = createCallbackFcn(app, @AppIconImageClicked, true);
            app.AppIcon.Tooltip = {'See the Data Source'};
            app.HelpIcon = uiimage(app.HeaderGrid);
            app.HelpIcon.ImageClickedFcn = createCallbackFcn(app, @HelpIconImageClicked, true);
            app.HelpIcon.Tooltip = {'About this App'};
            app.ContentGrid = uigridlayout(app.AppGrid);
            app.TempPanel = uipanel(app.ContentGrid);
            app.TempGrid = uigridlayout(app.TempPanel);
            app.CurrentConditionsLabel = uilabel(app.TempGrid);
            app.CurrentConditionsLabel.Text = 'Current Conditions';
            app.CTempGauge = uigauge(app.TempGrid, 'linear');
            app.CTempGauge.Limits = [-20 50];
            app.CTempGauge.MajorTicks = [-20 -10 0 10 20 30 40 50];
            app.CTempGauge.MinorTicks = [];
            app.TempLabel = uilabel(app.TempGrid);
            app.TempLabel.Text = '32°F';
            app.FTempGauge = uigauge(app.TempGrid, 'linear');
            app.FTempGauge.Limits = [0 120];
            app.FTempGauge.MajorTicks = [0 20 40 60 80 100 120];
            app.FTempGauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120'};
            app.FTempGauge.MinorTicks = [];
            app.FTempGauge.Value = 32;
            app.HumidityLabel = uilabel(app.TempGrid);
            app.HumidityLabel.Text = '💧 Humidity 💧';
            app.HumidGauge = uigauge(app.TempGrid, 'circular');
            app.HumidGauge.MajorTicks = [10 50 90];
            app.HumidGauge.MajorTickLabels = {''};
            app.HumidGauge.Value = 35;
            app.HumidLabel = uilabel(app.TempGrid);
            app.HumidLabel.Text = {''; ''; '35 %'};
            app.CFSwitch = uiswitch(app.TempGrid, 'slider');
            app.CFSwitch.Items = {'F', 'C'};
            app.CFSwitch.ValueChangedFcn = createCallbackFcn(app, @CFSwitchValueChanged, true);
            app.CFSwitch.Value = 'F';
            app.PressurePanel = uipanel(app.ContentGrid);
            app.PressureGrid = uigridlayout(app.PressurePanel);
            app.PressureTitle = uilabel(app.PressureGrid);
            app.PressureTitle.Text = '🌀 Pressure';
            app.RainTitle = uilabel(app.PressureGrid);
            app.RainTitle.Text = '☔ Rain';
            app.RainReadout = uieditfield(app.PressureGrid, 'numeric');
            app.RainReadout.ValueDisplayFormat = '%11.4g in/min';
            app.RainReadout.Editable = 'off';
            app.PressureReadout = uieditfield(app.PressureGrid, 'numeric');
            app.PressureReadout.ValueDisplayFormat = '%11.4g mmHg';
            app.PressureReadout.Editable = 'off';
            app.WindTitle = uilabel(app.PressureGrid);
            app.WindTitle.Text = '💨 Wind Direction & Speed';
            app.LocationPanel = uipanel(app.ContentGrid);
            app.WeatherChartGrid = uigridlayout(app.ContentGrid);
            app.WeatherChartPanel = uipanel(app.WeatherChartGrid);
            app.WeatherChartPanel.SizeChangedFcn = createCallbackFcn(app, @WeatherChartPanelSizeChanged, true);
            app.TodayPanel = uipanel(app.WeatherChartPanel);
            app.YesterPanel = uipanel(app.WeatherChartPanel);
            app.DaySwitch = uiswitch(app.WeatherChartGrid, 'slider');
            app.DaySwitch.Items = {'Today', 'Yesterday'};
            app.DaySwitch.ValueChangedFcn = createCallbackFcn(app, @DaySwitchValueChanged, true);
            app.DaySwitch.Value = 'Today';
            app.StatusPanel = uipanel(app.AppGrid);
            app.StatusGrid = uigridlayout(app.StatusPanel);
            app.RefreshDataLabel = uilabel(app.StatusGrid);
            app.RefreshDataLabel.Text = 'Data Refresh Message';
            app.TimeLabel = uilabel(app.StatusGrid);
            app.TimeLabel.Tooltip = {'Current Time in Natick, MA'};
            app.TimeLabel.Text = 'Time Here';
            app.GridLayout = uigridlayout(app.StatusGrid);
            app.StatusImage = uiimage(app.GridLayout);
            app.ShowRawDataCheckBox = uicheckbox(app.StatusGrid);
            app.ShowRawDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawDataCheckBoxValueChanged, true);
            app.ShowRawDataCheckBox.Tooltip = {'View thinned raw data over the past two days'};
            app.ShowRawDataCheckBox.Text = 'Show Raw Data    ';
            % Show the figure after all components are created
            app.NatickWeatherStationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NatickWeather

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NatickWeatherStationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NatickWeatherStationUIFigure)
        end
    end
end